classdef MRFAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TabGroup  matlab.ui.container.TabGroup
        Tab       matlab.ui.container.Tab
        Tab2      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function LoadMRFDataButtonPushed(app, event)
            [file,pth] = uigetfile();
            disp(fullfile(pth,file));
            resStruct = open(fullfile(pth,file));
            % Check that file has MRF T1 Map, MRF T2 map, Ref T1 Map and
            % Ref T2 Map
            if (isfield(resStruct,"T1Map_MRF") ~= 1)
                error("No MRF T1 map present");
            end

            if (isfield(resStruct,"T2Map_MRF") ~= 1)
                error("No MRF T2 map present");
            end
            
            if (isfield(resStruct,"T1Map_Ref") ~= 1)
                error("No reference T1 map present");
            end

            if (isfield(resStruct,"T2Map_Ref") ~= 1)
                error("No reference T2 map present");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRFAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end